classdef StochasticProcessesCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        STOCHASTICPROCESSESCALCULATORLabel  matlab.ui.control.Label
        TimevectorLabel                 matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        EnsembleMatrixfilenamematLabel  matlab.ui.control.Label
        StatisticalACFLabel             matlab.ui.control.Label
        nLabel                          matlab.ui.control.Label
        iLabel                          matlab.ui.control.Label
        jLabel                          matlab.ui.control.Label
        EnterprocessdataButton          matlab.ui.control.Button
        PlotMsignalsButton              matlab.ui.control.Button
        CalculateandplotstatisticalACFButton  matlab.ui.control.Button
        TimemeanofthenthsampleLabel     matlab.ui.control.Label
        TimeACFofthenthsampleLabel      matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        CalculateandplotthePSDButton    matlab.ui.control.Button
        CalculatethetotalAVGpowerButton  matlab.ui.control.Button
        PSDLabel                        matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        EditField_5                     matlab.ui.control.EditField
        EditField_6                     matlab.ui.control.EditField
        EditField_7                     matlab.ui.control.EditField
        EditField_8                     matlab.ui.control.EditField
        EditField_9                     matlab.ui.control.EditField
        EditField_10                    matlab.ui.control.EditField
        EnsemblemeanLabel               matlab.ui.control.Label
        EditField_11                    matlab.ui.control.EditField
        CalculateandplotButton          matlab.ui.control.Button
        TotalAVGpowerLabel_2            matlab.ui.control.Label
        EditField_12                    matlab.ui.control.EditField
        thematrixhastobenamedAinsidethefileLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterprocessdataButton
        function EnterprocessdataButtonPushed(app, event)

            t=str2num(app.EditField.Value);
            filename =app.EditField_2.Value;
            example = matfile(filename);
            ensemble = example.A;

            
        end

        % Button pushed function: PlotMsignalsButton
        function PlotMsignalsButtonPushed(app, event)
            t=str2num(app.EditField.Value);
            filename =app.EditField_2.Value;
            example = matfile(filename);
            ensemble = example.A;

            m=str2num(app.EditField_3.Value);
            figure('Name','M sample functions','NumberTitle','off');
            for k=1:m
                subplot(m,1,k);
                plot(t,ensemble(k,:))
                xlabel('time(s)');
                ylabel('sample function');
                title('sample function vs time');
                grid on
            end
        end

        % Button pushed function: CalculateandplotButton
        function CalculateandplotButtonPushed(app, event)
            t=str2num(app.EditField.Value);
            filename =app.EditField_2.Value;
            example = matfile(filename);
            ensemble = example.A;

            m=mean(ensemble);
            b=num2str(m);
            app.EditField_11.Value=strcat("[",b,"]");
            figure('Name','Ensemble Mean','NumberTitle','off');
            plot(t,m)
            xlabel('time(s)');
            ylabel('ensemble mean');
            title('Ensemble mean vs time');
            grid on
        
        end

        % Button pushed function: 
        % CalculateandplotstatisticalACFButton
        function CalculateandplotstatisticalACFButtonPushed(app, event)
            t=str2num(app.EditField.Value);
            filename =app.EditField_2.Value;
            example = matfile(filename);
            ensemble = example.A;

            i=str2num(app.EditField_4.Value);
            j=str2num(app.EditField_5.Value);
            [r,col]=size(ensemble);
            
            for k=1:r
  
                A(k)=ensemble(k,i)*ensemble(k,j);
               
            end
            SACF=sum(A)/r;
            app.EditField_6.Value=num2str(SACF);
            f=1;
            
            for x=1:col
                for y=1:col
                    for z=1:r
                     b(z)=ensemble(z,x)*ensemble(z,y);
                    end
                   
                    c(f)=sum(b)/r;
                    m(f)=x;
                    n(f)=y;
                    f=f+1;
                end
            end
           
            figure('Name','statistical ACF for every i and j','NumberTitle','off');
            [q,w]=meshgrid(1:col,1:col);
            e=reshape(c,col,col);
            disp(e);
            surf(q,w,e)
            title('statistical ACF for every i and j');
            xlabel('i');
            ylabel('j');
            zlabel('ACF');
          
                 
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            t=str2num(app.EditField.Value);
            filename =app.EditField_2.Value;
            example = matfile(filename);
            ensemble = example.A;

             [r,c]=size(ensemble);
             n=str2num(app.EditField_7.Value);
             tm=sum(ensemble(n,:))*(t(2)-t(1));
             app.EditField_8.Value=num2str(tm);
              a=ensemble(n,:);
              
             for tao=1:length(a)
                 v(tao)=0;
                 for i=1:length(a)
                     if i+tao>length(a)
                         break
                     else
                         s=a(i)*a(i+tao);
                         v(tao)=v(tao)+s;
                     end
                 end        
             end
             app.EditField_9.Value=num2str(v(1:length(v)-1)/c);


        end

        % Button pushed function: CalculateandplotthePSDButton
        function CalculateandplotthePSDButtonPushed(app, event)
            filename =app.EditField_2.Value;
            example = matfile(filename);
            ensemble = example.A;
            t=str2num(app.EditField.Value);

             [r,c]=size(ensemble);
             n=str2num(app.EditField_7.Value);
             a=ensemble(n,:);
              
             for tao=1:length(a)
                 v(tao)=0
                 for i=1:length(a)
                     if i+tao>length(a)
                         break
                     else
                         s=a(i)*a(i+tao);
                         v(tao)=v(tao)+s;
                     end
                 end        
             end
             
             psd=fftshift(fft(v/c));
             app.EditField_10.Value=num2str(psd);
             figure('Name','PSD','NumberTitle','off');
             Fs=1/(t(2)-t(1));
            axis=linspace(-Fs/2,Fs/2,length(psd));
            plot(axis,psd)
            xlabel('F(hz)');
            ylabel('PSD');
            title('PSD vs Frequency');
            grid on
             
        end

        % Button pushed function: CalculatethetotalAVGpowerButton
        function CalculatethetotalAVGpowerButtonPushed(app, event)
            filename =app.EditField_2.Value;
            example = matfile(filename);
            ensemble = example.A;

             f=1;
             [r,col]=size(ensemble);
            
            
            f=1;
            
            for x=1:col
                for y=1:col
                    for z=1:r
                        b(z)=ensemble(z,x)*ensemble(z,y);
                    end
                   
                    c(f)=sum(b)/r;
                    m(f)=x;
                    n(f)=y;
                    f=f+1;
                end
            end
           
             avgP=0;
             a=[m;n;c];
             e=transpose(a);
             for i=1:length(e)
                 if e(i,1)==e(i,2)
                     avgP=avgP+e(i,3);
                 end
             end
             app.EditField_12.Value=num2str(avgP/r);
       
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.STOCHASTICPROCESSESCALCULATORLabel = uilabel(app.UIFigure);
            app.STOCHASTICPROCESSESCALCULATORLabel.Text = 'STOCHASTIC PROCESSES CALCULATOR';
            app.TimevectorLabel = uilabel(app.UIFigure);
            app.TimevectorLabel.Text = 'Time vector';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.EnsembleMatrixfilenamematLabel = uilabel(app.UIFigure);
            app.EnsembleMatrixfilenamematLabel.Text = 'Ensemble Matrix file name(.mat)';
            app.StatisticalACFLabel = uilabel(app.UIFigure);
            app.StatisticalACFLabel.Text = 'Statistical ACF';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n';
            app.iLabel = uilabel(app.UIFigure);
            app.iLabel.Text = 'i';
            app.jLabel = uilabel(app.UIFigure);
            app.jLabel.Text = {'j'; ''};
            app.EnterprocessdataButton = uibutton(app.UIFigure, 'push');
            app.EnterprocessdataButton.ButtonPushedFcn = createCallbackFcn(app, @EnterprocessdataButtonPushed, true);
            app.EnterprocessdataButton.Text = 'Enter process data';
            app.PlotMsignalsButton = uibutton(app.UIFigure, 'push');
            app.PlotMsignalsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMsignalsButtonPushed, true);
            app.PlotMsignalsButton.Text = 'Plot M signals';
            app.CalculateandplotstatisticalACFButton = uibutton(app.UIFigure, 'push');
            app.CalculateandplotstatisticalACFButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandplotstatisticalACFButtonPushed, true);
            app.CalculateandplotstatisticalACFButton.Text = {'Calculate and plot statistical ACF'; ''};
            app.TimemeanofthenthsampleLabel = uilabel(app.UIFigure);
            app.TimemeanofthenthsampleLabel.Text = {'Time mean of the nth sample'; ''};
            app.TimeACFofthenthsampleLabel = uilabel(app.UIFigure);
            app.TimeACFofthenthsampleLabel.Text = {'Time ACF of the nth sample'; ''};
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = {'Calculate'; ''};
            app.CalculateandplotthePSDButton = uibutton(app.UIFigure, 'push');
            app.CalculateandplotthePSDButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandplotthePSDButtonPushed, true);
            app.CalculateandplotthePSDButton.Text = 'Calculate and plot the PSD';
            app.CalculatethetotalAVGpowerButton = uibutton(app.UIFigure, 'push');
            app.CalculatethetotalAVGpowerButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatethetotalAVGpowerButtonPushed, true);
            app.CalculatethetotalAVGpowerButton.Text = {'Calculate the total AVG power'; ''};
            app.PSDLabel = uilabel(app.UIFigure);
            app.PSDLabel.Text = 'PSD';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.EditField_9.Editable = 'off';
            app.EditField_10 = uieditfield(app.UIFigure, 'text');
            app.EditField_10.Editable = 'off';
            app.EnsemblemeanLabel = uilabel(app.UIFigure);
            app.EnsemblemeanLabel.Text = 'Ensemble mean';
            app.EditField_11 = uieditfield(app.UIFigure, 'text');
            app.EditField_11.Editable = 'off';
            app.CalculateandplotButton = uibutton(app.UIFigure, 'push');
            app.CalculateandplotButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandplotButtonPushed, true);
            app.CalculateandplotButton.Text = 'Calculate and plot ';
            app.TotalAVGpowerLabel_2 = uilabel(app.UIFigure);
            app.TotalAVGpowerLabel_2.Text = 'Total AVG power';
            app.EditField_12 = uieditfield(app.UIFigure, 'text');
            app.EditField_12.Editable = 'off';
            app.thematrixhastobenamedAinsidethefileLabel = uilabel(app.UIFigure);
            app.thematrixhastobenamedAinsidethefileLabel.Text = {'(the matrix has to be named ''A'' inside the file)'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StochasticProcessesCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end